classdef ArsalanprostateApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcaciasProstatedetectorUIFigure  matlab.ui.Figure
        LoadImageFolderButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        PatientNameEditFieldLabel       matlab.ui.control.Label
        PatientNameEditField            matlab.ui.control.EditField
        PatientIDEditFieldLabel         matlab.ui.control.Label
        PatientIDEditField              matlab.ui.control.EditField
        BirthDateEditFieldLabel         matlab.ui.control.Label
        BirthDateEditField              matlab.ui.control.EditField
        StudyIDEditFieldLabel           matlab.ui.control.Label
        StudyIDEditField                matlab.ui.control.EditField
        StudyDateEditFieldLabel         matlab.ui.control.Label
        StudyDateEditField              matlab.ui.control.EditField
        SliceLocationEditFieldLabel     matlab.ui.control.Label
        SliceLocationEditField          matlab.ui.control.EditField
        InstanceNoEditFieldLabel        matlab.ui.control.Label
        InstanceNoEditField             matlab.ui.control.EditField
        TotalNoofImagesEditFieldLabel   matlab.ui.control.Label
        TotalNoofImagesEditField        matlab.ui.control.EditField
        NextImageButton                 matlab.ui.control.Button
        PreviousImageButton             matlab.ui.control.Button
        AnonymizeImagesandsaveButton    matlab.ui.control.Button
        ConvertDicomtoJPGandsaveButton  matlab.ui.control.Button
        ConvertJPGtoDicomandsaveButton  matlab.ui.control.Button
        SegmentationtoolboxLabel        matlab.ui.control.Label
        ActiveContourSegmentationButton  matlab.ui.control.Button
        SaveImageButton                 matlab.ui.control.Button
        Show3DButton                    matlab.ui.control.Button
        AreaofSegmentedImageEditFieldLabel  matlab.ui.control.Label
        AreaofSegmentedImageEditField   matlab.ui.control.NumericEditField
        VolumeofSegmentedImageEditFieldLabel  matlab.ui.control.Label
        VolumeofSegmentedImageEditField  matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        currentIndex = 1 % Description
        info_original; % Description
        numFiles; % Description
        filenames; % Description
        files % Description
        currentDir % Description
        image_dicom % Description
        anonymize; % Description
        roi_type; % Description
        masks; % Description
        bw; % Description
        seg_Images; % Description
    end


    methods (Access = private)

        % Button pushed function: LoadImageFolderButton
        function LoadImageFolderButtonPushed(app, event)
            currentIndex = app.currentIndex; 
            %info_original = app.info_original;
            numFiles = app.numFiles;
            
            
            % get the ui for selecting folder
            currentDir = uigetdir('cwd');
            
            if currentDir == 0
              % User clicked the Cancel button.
                return;
            end
            app.currentDir = currentDir
            % get folder
            files = dir2(currentDir);
            app.files = files;
            % number of files in selected folder
            numFiles = length(files); 
            app.numFiles = numFiles;
            %to sort filenames
            filenames = natsortfiles({files.name});
            app.filenames = filenames
            for nfile = 1:numFiles
                info_original(nfile) = dicominfo([currentDir, '\', filenames{nfile}]);  % read dicominfo
                image_dicom(:,:,nfile) = dicomread(info_original(nfile)); % read dicom image from info
                
            end
            %imshow(image_dicom(:,:,1), 'parent', app.UIAxes);
            app.UIAxes.reset
            I = imshow(image_dicom(:,:,1),[], 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            %Displaying Patient Data
            %app.currentIndex = '1';


            patientName = strcat(info_original(1).PatientName.GivenName,...
                {' '}, info_original(1).PatientName.FamilyName);
            
            %converting cell to matrix than matrix to string since 
            %app.Edit works with string
            patientName = num2str(cell2mat(patientName));
            app.PatientNameEditField.Value = patientName;
            % Display Patient ID
            PatientID = num2str(info_original(currentIndex).PatientID);
            app.PatientIDEditField.Value = PatientID;
            % Display Birthdate
            Birthdate = num2str(info_original(currentIndex).PatientBirthDate);
            app.BirthDateEditField.Value = Birthdate;
            % Display Study Date
            StudyDate = num2str(info_original(currentIndex).StudyDate);
            app.StudyDateEditField.Value = StudyDate;
            % Display Study ID
            StudyID = num2str(info_original(currentIndex).StudyID);
            app.StudyIDEditField.Value = StudyID;
            % Display Slice Location
            SliceLocation = num2str(info_original(currentIndex).SliceLocation);
            app.SliceLocationEditField.Value = SliceLocation;
            % Display Instance No
            InstanceNo = num2str(info_original(currentIndex).InstanceNumber);
            app.InstanceNoEditField.Value = InstanceNo;
            % Display Total No of Images
            app.TotalNoofImagesEditField.Value = num2str(numFiles);
            %app.TotalNoofImagesEditField.Value = app.x;
            
            
            app.info_original = info_original;
            app.image_dicom = image_dicom;
            
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            currentIndex = app.currentIndex + 1;  
            info_original =  app.info_original;
            image_dicom = app.image_dicom;
            numFiles = app.numFiles;
            if (currentIndex == numFiles +1)
                
                currentIndex = 1;
            end
            
            patientName = strcat(info_original(currentIndex).PatientName.GivenName,...
                {' '}, info_original(currentIndex).PatientName.FamilyName);
            
            %converting cell to matrix than matrix to string since 
            %app.Edit works with string
            patientName = num2str(cell2mat(patientName));
            app.PatientNameEditField.Value = patientName;
            % Display Patient ID
            PatientID = num2str(info_original(currentIndex).PatientID);
            app.PatientIDEditField.Value = PatientID;
            % Display Birthdate
            Birthdate = num2str(info_original(currentIndex).PatientBirthDate);
            app.BirthDateEditField.Value = Birthdate;
            % Display Study Date
            StudyDate = num2str(info_original(currentIndex).StudyDate);
            app.StudyDateEditField.Value = StudyDate;
            % Display Study ID
            StudyID = num2str(info_original(currentIndex).StudyID);
            app.StudyIDEditField.Value = StudyID;
            % Display Slice Location
            SliceLocation = num2str(info_original(currentIndex).SliceLocation);
            app.SliceLocationEditField.Value = SliceLocation;
            % Display Instance No
            InstanceNo = num2str(info_original(currentIndex).InstanceNumber);
            app.InstanceNoEditField.Value = InstanceNo;
            % Display Total No of Images
            app.TotalNoofImagesEditField.Value = num2str(numFiles);
            
            app.UIAxes.reset
            I = imshow(image_dicom(:,:,currentIndex),[], 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            app.currentIndex = currentIndex;
            
        end

        % Button pushed function: PreviousImageButton
        function PreviousImageButtonPushed(app, event)
            currentIndex = app.currentIndex - 1;  
            if (currentIndex == 0)
                
                currentIndex = 1;
            end
              
            info_original =  app.info_original;
            image_dicom = app.image_dicom;
            numFiles = app.numFiles;
            
            patientName = strcat(info_original(currentIndex).PatientName.GivenName,...
                {' '}, info_original(currentIndex).PatientName.FamilyName);
            
            %converting cell to matrix than matrix to string since 
            %app.Edit works with string
            patientName = num2str(cell2mat(patientName));
            app.PatientNameEditField.Value = patientName;
            % Display Patient ID
            PatientID = num2str(info_original(currentIndex).PatientID);
            app.PatientIDEditField.Value = PatientID;
            % Display Birthdate
            Birthdate = num2str(info_original(currentIndex).PatientBirthDate);
            app.BirthDateEditField.Value = Birthdate;
            % Display Study Date
            StudyDate = num2str(info_original(currentIndex).StudyDate);
            app.StudyDateEditField.Value = StudyDate;
            % Display Study ID
            StudyID = num2str(info_original(currentIndex).StudyID);
            app.StudyIDEditField.Value = StudyID;
            % Display Slice Location
            SliceLocation = num2str(info_original(currentIndex).SliceLocation);
            app.SliceLocationEditField.Value = SliceLocation;
            % Display Instance No
            InstanceNo = num2str(info_original(currentIndex).InstanceNumber);
            app.InstanceNoEditField.Value = InstanceNo;
            % Display Total No of Images
            app.TotalNoofImagesEditField.Value = num2str(numFiles);
            
            app.UIAxes.reset
            I = imshow(image_dicom(:,:,currentIndex),[], 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            
            app.currentIndex = currentIndex;
             
        end

        % Button pushed function: AnonymizeImagesandsaveButton
        function AnonymizeImagesandsaveButtonPushed(app, event)
            anonymize = app.info_original;
            if isempty(anonymize)
              % User clicked the Cancel button.
                message = sprintf(' Please load images first');
                uiwait(msgbox(message,'Warning'));
                return;
            end
            %filein = app.files;
            %dicomanon(filein , 'anony_file.dcm');
            givenNameLength = length(anonymize(1).PatientName.GivenName);
            familyNameLength = length(anonymize(1).PatientName.FamilyName);
            patientIDLength = length(anonymize(1).PatientID);
            birthDateLength = length(anonymize(1).PatientBirthDate);
            
            for nfiles=1:length(anonymize)
                anonymize(nfiles).PatientName.GivenName = repmat('x',1,givenNameLength);
                anonymize(nfiles).PatientName.FamilyName = repmat('x', 1, familyNameLength);
                anonymize(nfiles).PatientID = repmat('x', 1, patientIDLength);
                anonymize(nfiles).PatientBirthDate = repmat('x', 1, birthDateLength);
            end
            
            mkdir('./Anonymized Images');
            anonymized_save = anonymize;
            image_dicom_save = app.image_dicom;
            
            for nfiles = 1:length(anonymized_save)
                filename = strcat('./Anonymized Images/Image000',num2str(nfiles),'.dcm');
                dicomwrite(image_dicom_save(:,:,nfiles), filename, anonymized_save(nfiles));
            end
            
            app.anonymize = anonymize;
            
            message = sprintf(' Anonymization complete \n Check Current Directory');
                uiwait(msgbox(message,'Success'));
        end

        % Button pushed function: ConvertDicomtoJPGandsaveButton
        function ConvertDicomtoJPGandsaveButtonPushed(app, event)
            anonymized_save = app.info_original;
            image_dicom_tojpeg = app.image_dicom;
            mkdir('./Jpeg Images');
            mkdir('./Meta Data');
            
            for nfiles = 1:length(anonymized_save)
                filename = strcat('./Jpeg Images/Image',num2str(nfiles),'.jpeg');
                metadata = strcat('./Meta Data/data',num2str(nfiles),'.mat');
                image_dicom_tojpeg8(:,:,nfiles) = uint8(255 * mat2gray(image_dicom_tojpeg(:,:,nfiles)));
                imwrite(image_dicom_tojpeg8(:,:,nfiles), filename);
                info_anonymized_forjpeg = anonymized_save(nfiles);
                save(metadata, 'info_anonymized_forjpeg');
            end  
            message = sprintf(' Conversion complete \nCheck Current Directory');
                uiwait(msgbox(message,'Success'));
            
        end

        % Button pushed function: ConvertJPGtoDicomandsaveButton
        function ConvertJPGtoDicomandsaveButtonPushed(app, event)
            currentDir = uigetdir('cwd','Please Select the JPG folder');
            if currentDir ==0
              % User clicked the Cancel button.
                message = sprintf(' Please load images first');
                uiwait(msgbox(message,'Warning'));
                return;
            end
            files = dir([currentDir, '.\*.jpeg']);
            
            numFiles = length(files);
            app.numFiles = numFiles;
            
            for nfile = 1:numFiles
                image_jpeg(:,:,nfile) = imread([currentDir, '\', files(nfile).name]);  % read jpeg images
            end
            %app.image_jpeg = image_jpeg;
            
            % getting dicom information (meta data)
            currentDir = uigetdir('cwd','Please Select the metadata folder');
            files = dir([currentDir, '.\*.mat']);
            numFiles = length(files);
            
            for nfile = 1:numFiles
                info_dicom_forjpeg(nfile) = load([currentDir, '\', files(nfile).name]);  % read jpeg images
            end
            
            mkdir('./Dicom Images');
            % Saving Dicom
            for nfile = 1:numFiles
                filename = strcat('./Dicom Images/Image',num2str(nfile),'.dcm');
                
                dicomwrite(image_jpeg(:,:,nfile), filename, info_dicom_forjpeg(nfile));
            end
            
             
            message = sprintf(' Conversion complete \nCheck Current Directory');
                uiwait(msgbox(message,'Success'));
            
            
        end

        % Button pushed function: ActiveContourSegmentationButton
        function ActiveContourSegmentationButtonPushed(app, event)
            
            mkdir('./Segmented Images/Peripheral');
            mkdir('./Segmented Images/tumor');
            mkdir('./Segmented Images/Central');
            mkdir('./Segmented Images/temporal');


            currentIndex = app.currentIndex;
            image_dicom_tosegment = app.image_dicom(:,:,currentIndex);
            if isempty(image_dicom_tosegment)
              % User clicked the Cancel button.
                message = sprintf(' Please load image');
                uiwait(msgbox(message,'Warning'));
                return;
            end
            
            message = sprintf(' Draw the contour with left click.\nand lift the mouse button to finish');
            uiwait(msgbox(message,'Draw Contour'));
            
            %app.UIAxes.reset
            %I = imshow(,[], 'Parent', app.UIAxes, ...
            %'XData', [1 app.UIAxes.Position(3)], ...
            %'YData', [1 app.UIAxes.Position(4)]);
            %ax = axes(app.UIAxes);
             %H = drawrectangle(ax);
            %H = rectangle(app.UIAxes, 'Position', [25,25,50,50])
            %H = imfreehand(gca,'closed','false');
            
            fig = figure;
            imshow(image_dicom_tosegment,[]);                % Image data stored in property, no axes called
            set(fig, 'Position', get(0, 'Screensize'));  % Fullscreen image
            set(fig,'CloseRequestFcn','')                % Prevent pop-up from closing
            set(fig, 'MenuBar', 'none');
            set(fig, 'ToolBar', 'none');
            % Need this while loop because mouse events will prematurely exit
            % the ROI functions, even with the CloseRequestFcn turned off
            roi = [];
            
            prompt = {'Enter Iteration no:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'200'};
            iter = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(iter)
              % User clicked the Cancel button.
                delete(fig)
                return;
            end
            
            prompt = {'Enter Smoothness Factor:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'1.5'};
            smoothf = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(smoothf)
              % User clicked the Cancel button.
                delete(fig)
                return;
            end
            
            
            list = {'Circular','Rectangle','Freehand'};
            [indx,tf] = listdlg('ListString',list,'SelectionMode','single');
            
            if indx == 1
                app.roi_type = 'Circular';
            elseif indx == 2
                app.roi_type = 'Rectangle';
            elseif indx == 3
                app.roi_type = 'Freehand';
            else
                app.roi_type = 'Freehand';
            end
            
            %app.roi_type='Circular';
            while isempty(roi) 
                switch app.roi_type
                    case 'Circular'; roi = imellipse();
                    case 'Rectangle'; roi = imrect();
                    case 'Freehand'; roi = imfreehand();
                end
            end
            app.masks = createMask(roi); % Binary matrices stored in cell array
            %print(app.mask{end+1})
            app.bw = activecontour(image_dicom_tosegment,app.masks,str2double( iter{1}) ,'edge','SmoothFactor',str2double( smoothf{1}) );
            
            
            app.UIAxes.reset
            I = imshow(app.bw,[], 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);


            %imwrite(binaryImageTemporal,'./Segmented Images/Temporal.jpeg');
            %ax = axes(app.UIAxes);
             %H = drawrectangle(ax);
            %H = rectangle(app.UIAxes, 'Position', [25,25,50,50])
            %H = imfreehand(gca,'closed','false');
            
            delete(fig)
            
            seg=app.bw;
            stats = regionprops(seg , 'Area');
            allArea = [stats.Area];
            % To get pixel spacing
            pixel_spacing = app.info_original(currentIndex).PixelSpacing;
            
            area = allArea * pixel_spacing;
            areaImage = [];
            area = [areaImage area]
            app.AreaofSegmentedImageEditField.Value = area(1,1);
            
            % Volume 
            Slice_thickness = app.info_original(currentIndex).	SliceThickness;
            volume = area(1,1) * Slice_thickness;
            app.VolumeofSegmentedImageEditField.Value = volume;
            
            %binaryImageTumor = H.createMask;
            %bw = activecontour(image_dicom_tosegment,binaryImageTumor,300);
            
            %figure, imshow(bw);
            
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            list = {'Temporal','Tumor','Central','Peripheral'};
            [indx,tf] = listdlg('ListString',list,'SelectionMode','single');
            
            if indx == 1
                saveat = './Segmented Images/temporal/Temporal';
            elseif indx == 2
                saveat = './Segmented Images/tumor/Tumor';
            elseif indx == 3
                saveat = './Segmented Images/Central/Central';
            else
                saveat = './Segmented Images/Peripheral/Peripheral';
            end
            if isempty(app.info_original)
              % User clicked the Cancel button.
                message = sprintf(' saving failed. Please load image first');
                uiwait(msgbox(message,'Fail'));
                return;
            end
            
            
            definput = {num2str(app.info_original(app.currentIndex).InstanceNumber)};
            prompt = {'Input Slice / Instance no for 3D Reconstruction:'};
            dlgtitle = 'Input';
            dims = [1 35];
            
            instanceno = inputdlg(prompt,dlgtitle,dims,definput);
            if isempty(instanceno)
              % User clicked the Cancel button.
                
                return;
            end
            
            saveat = strcat(saveat,instanceno{1},'.jpeg')
            
            imwrite(app.bw,saveat);
        end

        % Button pushed function: Show3DButton
        function Show3DButtonPushed(app, event)
            currentDir = uigetdir('cwd','Please select the Segmented Image Folder');
            files = dir2([currentDir, '.\*.jpeg']);
            numFiles = length(files);
            
            filenames = natsortfiles({files.name});
            
            
            for nfile = 1:numFiles
                filename = strcat(currentDir,'\',filenames{nfile});
                Images(:,:,nfile) = imread(filename);
            end
            app.seg_Images = Images;
            
            drawSurface(app.seg_Images);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AcaciasProstatedetectorUIFigure = uifigure;
            app.AcaciasProstatedetectorUIFigure.Name = 'Acacias Prostate detector';
            setAutoResize(app, app.AcaciasProstatedetectorUIFigure, true)
            app.LoadImageFolderButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.LoadImageFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageFolderButtonPushed, true);
            app.LoadImageFolderButton.Text = 'Load Image Folder';
            app.UIAxes = uiaxes(app.AcaciasProstatedetectorUIFigure);
            app.PatientNameEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.PatientNameEditFieldLabel.Text = 'Patient Name';
            app.PatientNameEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'text');
            app.PatientIDEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.PatientIDEditFieldLabel.Text = 'PatientID';
            app.PatientIDEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'text');
            app.BirthDateEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.BirthDateEditFieldLabel.Text = 'BirthDate';
            app.BirthDateEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'text');
            app.StudyIDEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.StudyIDEditFieldLabel.Text = 'StudyID';
            app.StudyIDEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'text');
            app.StudyDateEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.StudyDateEditFieldLabel.Text = 'StudyDate';
            app.StudyDateEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'text');
            app.SliceLocationEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.SliceLocationEditFieldLabel.Text = 'SliceLocation';
            app.SliceLocationEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'text');
            app.InstanceNoEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.InstanceNoEditFieldLabel.Text = 'Instance No';
            app.InstanceNoEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'text');
            app.TotalNoofImagesEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.TotalNoofImagesEditFieldLabel.Text = 'Total No of Images';
            app.TotalNoofImagesEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'text');
            app.NextImageButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            app.PreviousImageButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousImageButtonPushed, true);
            app.PreviousImageButton.Text = 'Previous Image';
            app.AnonymizeImagesandsaveButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.AnonymizeImagesandsaveButton.ButtonPushedFcn = createCallbackFcn(app, @AnonymizeImagesandsaveButtonPushed, true);
            app.AnonymizeImagesandsaveButton.Text = 'Anonymize Images and save';
            app.ConvertDicomtoJPGandsaveButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.ConvertDicomtoJPGandsaveButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertDicomtoJPGandsaveButtonPushed, true);
            app.ConvertDicomtoJPGandsaveButton.Text = 'Convert Dicom to JPG and save';
            app.ConvertJPGtoDicomandsaveButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.ConvertJPGtoDicomandsaveButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertJPGtoDicomandsaveButtonPushed, true);
            app.ConvertJPGtoDicomandsaveButton.Text = 'Convert JPG to Dicom and save';
            app.SegmentationtoolboxLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.SegmentationtoolboxLabel.Text = 'Segmentation toolbox';
            app.ActiveContourSegmentationButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.ActiveContourSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ActiveContourSegmentationButtonPushed, true);
            app.ActiveContourSegmentationButton.Text = 'Active Contour Segmentation';
            app.SaveImageButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image ';
            app.Show3DButton = uibutton(app.AcaciasProstatedetectorUIFigure, 'push');
            app.Show3DButton.ButtonPushedFcn = createCallbackFcn(app, @Show3DButtonPushed, true);
            app.Show3DButton.Text = 'Show 3D';
            app.AreaofSegmentedImageEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.AreaofSegmentedImageEditFieldLabel.Text = 'Area of Segmented Image';
            app.AreaofSegmentedImageEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'numeric');
            app.VolumeofSegmentedImageEditFieldLabel = uilabel(app.AcaciasProstatedetectorUIFigure);
            app.VolumeofSegmentedImageEditFieldLabel.Text = 'Volume of Segmented Image';
            app.VolumeofSegmentedImageEditField = uieditfield(app.AcaciasProstatedetectorUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ArsalanprostateApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcaciasProstatedetectorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcaciasProstatedetectorUIFigure)
        end
    end
end